classdef latexglovepage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ClearImageButton              matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
        OutputImageLabel              matlab.ui.control.Label
        uploadimage                   matlab.ui.control.Image
        UploadImageButton             matlab.ui.control.Button
        outputimage                   matlab.ui.control.Image
        ContaminationDetectionButton  matlab.ui.control.Button
        RipDetectionButton            matlab.ui.control.Button
        HolesDetectionButton          matlab.ui.control.Button
        LatexGlovePageLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Retrieve the screen size
            screenSize = get(groot, 'ScreenSize'); % This is more reliable for getting screen size
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            % Get the app's current width and height (assuming it's already set to desired size)
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            
            % Calculate the new position to center the app
            newPositionX = (screenWidth - appWidth) / 2;
            newPositionY = (screenHeight - appHeight) / 2;
            
            % Update the app's position
            app.UIFigure.Position = [newPositionX, newPositionY, appWidth, appHeight];
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            mainpage;
            closereq;
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
           [file, path] = uigetfile({'*.jpg;*.png;*.jpeg;', 'Images (*.jpg, *.png, *.jpeg)'}, 'Select an Image');
            if isequal(file, 0)
                disp('User selected Cancel');
            else
               % Read the selected image
                selectedImage = imread(fullfile(path, file));
        
                % Display the image in the Image component
                app.uploadimage.ImageSource = selectedImage;
            figure(app.UIFigure);
            end 
        end

        % Button pushed function: ClearImageButton
        function ClearImageButtonPushed(app, event)
            % Create a transparent image
            transparentImage = uint8(zeros(1, 1, 3)); % Creating a 1x1x3 transparent image
            app.uploadimage.ImageSource = transparentImage;  
    
            % Create a transparent image
            transparentImage = uint8(zeros(1, 1, 3)); % Creating a 1x1x3 transparent image
            app.outputimage.ImageSource = transparentImage;
    
            uialert(app.UIFigure, 'Upload image & Output image is cleared', 'Clear Image', 'Icon', 'info');
        end

        % Button pushed function: HolesDetectionButton
        function HolesDetectionButtonPushed(app, event)
            % Assuming this is part of a button callback in App Designer
            % and that app.UploadImage.Image contains the source image data
            sourceImage = app.uploadimage.ImageSource; % Get the image data from the UploadImage component
            resultImage = detectHoles(sourceImage); % Process the image
            app.outputimage.ImageSource = resultImage; % Display the result in the OutputImage component
            uialert(app.UIFigure, 'Holes Detected', 'Detected Information ', 'Icon', 'info');
        end

        % Button pushed function: RipDetectionButton
        function RipDetectionButtonPushed(app, event)
            % Assuming this is part of a button callback in App Designer
            % and that app.UploadImage.Image contains the source image data
            sourceImage = app.uploadimage.ImageSource; % Get the image data from the UploadImage component
            resultImage = ripDetection(sourceImage); % Process the image
            app.outputimage.ImageSource = resultImage; % Display the result in the OutputImage component
            uialert(app.UIFigure, 'Rip Holes Detected', 'Detected Information ', 'Icon', 'info');
        end

        % Button pushed function: ContaminationDetectionButton
        function ContaminationDetectionButtonPushed(app, event)
            % Assuming this is part of a button callback in App Designer
            % and that app.UploadImage.Image contains the source image data
            sourceImage = app.uploadimage.ImageSource; % Get the image data from the UploadImage component
            resultImage = detectGreenContamination(sourceImage); % Process the image
            app.outputimage.ImageSource = resultImage; % Display the result in the OutputImage component
            uialert(app.UIFigure, 'Contamination Holes Detected', 'Detected Information ', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LatexGlovePageLabel = uilabel(app.UIFigure);
            app.LatexGlovePageLabel.Text = 'Latex Glove Page';
            app.HolesDetectionButton = uibutton(app.UIFigure, 'push');
            app.HolesDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @HolesDetectionButtonPushed, true);
            app.HolesDetectionButton.Text = 'Holes Detection';
            app.RipDetectionButton = uibutton(app.UIFigure, 'push');
            app.RipDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @RipDetectionButtonPushed, true);
            app.RipDetectionButton.Text = 'Rip Detection';
            app.ContaminationDetectionButton = uibutton(app.UIFigure, 'push');
            app.ContaminationDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ContaminationDetectionButtonPushed, true);
            app.ContaminationDetectionButton.Text = 'Contamination Detection';
            app.outputimage = uiimage(app.UIFigure);
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.uploadimage = uiimage(app.UIFigure);
            app.OutputImageLabel = uilabel(app.UIFigure);
            app.OutputImageLabel.Text = 'Output Image';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ClearImageButton = uibutton(app.UIFigure, 'push');
            app.ClearImageButton.ButtonPushedFcn = createCallbackFcn(app, @ClearImageButtonPushed, true);
            app.ClearImageButton.Text = 'Clear Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = latexglovepage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end