classdef mainpage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WelcometotheGloveDetectionWorldLabel  matlab.ui.control.Label
        CottonGloveButton         matlab.ui.control.Button
        BurnHolesDetectionStainsDetectionMissingFingerDetectionLabel_3  matlab.ui.control.Label
        HoleDetectionRipDetectionContaminationDetectionLabel  matlab.ui.control.Label
        BurnHolesDetectionStainsDetectionMissingFingerDetectionLabel  matlab.ui.control.Label
        FeaturesAvailableLabel_3  matlab.ui.control.Label
        FeaturesAvailableLabel_2  matlab.ui.control.Label
        FeaturesAvailableLabel    matlab.ui.control.Label
        NutrilleGloveImage        matlab.ui.control.Image
        LatexGloveImage           matlab.ui.control.Image
        CottonGloveImage          matlab.ui.control.Image
        IntructionText            matlab.ui.control.Label
        NitrileGloveButton        matlab.ui.control.Button
        LatexGloveButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Retrieve the screen size
            screenSize = get(groot, 'ScreenSize'); % This is more reliable for getting screen size
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            
            % Get the app's current width and height (assuming it's already set to desired size)
            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);
            
            % Calculate the new position to center the app
            newPositionX = (screenWidth - appWidth) / 2;
            newPositionY = (screenHeight - appHeight) / 2;
            
            % Update the app's position
            app.UIFigure.Position = [newPositionX, newPositionY, appWidth, appHeight];
        end

        % Button pushed function: CottonGloveButton
        function CottonGloveButtonPushed(app, event)
            cottonglovepage;
            closereq;
        end

        % Button pushed function: LatexGloveButton
        function LatexGloveButtonPushed(app, event)
            latexglovepage;
            closereq;
        end

        % Button pushed function: NitrileGloveButton
        function NitrileGloveButtonPushed(app, event)
            nitrileglovepage;
            closereq;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LatexGloveButton = uibutton(app.UIFigure, 'push');
            app.LatexGloveButton.ButtonPushedFcn = createCallbackFcn(app, @LatexGloveButtonPushed, true);
            app.LatexGloveButton.Text = 'Latex Glove';
            app.NitrileGloveButton = uibutton(app.UIFigure, 'push');
            app.NitrileGloveButton.ButtonPushedFcn = createCallbackFcn(app, @NitrileGloveButtonPushed, true);
            app.NitrileGloveButton.Text = 'Nitrile Glove';
            app.IntructionText = uilabel(app.UIFigure);
            app.IntructionText.Text = 'Please select the glove types you wish to proceed.';
            app.CottonGloveImage = uiimage(app.UIFigure);
            app.CottonGloveImage.ImageSource = fullfile(pathToMLAPP, 'CottonGlove.jpg');
            app.LatexGloveImage = uiimage(app.UIFigure);
            app.LatexGloveImage.ImageSource = fullfile(pathToMLAPP, 'LatexGlove.jpg');
            app.NutrilleGloveImage = uiimage(app.UIFigure);
            app.NutrilleGloveImage.ImageSource = fullfile(pathToMLAPP, 'Nutrille Glove.png');
            app.FeaturesAvailableLabel = uilabel(app.UIFigure);
            app.FeaturesAvailableLabel.Text = 'Features Available';
            app.FeaturesAvailableLabel_2 = uilabel(app.UIFigure);
            app.FeaturesAvailableLabel_2.Text = 'Features Available';
            app.FeaturesAvailableLabel_3 = uilabel(app.UIFigure);
            app.FeaturesAvailableLabel_3.Text = 'Features Available';
            app.BurnHolesDetectionStainsDetectionMissingFingerDetectionLabel = uilabel(app.UIFigure);
            app.BurnHolesDetectionStainsDetectionMissingFingerDetectionLabel.Text = {'-Burn Holes Detection'; '-Stains Detection'; '-Missing Finger Detection'};
            app.HoleDetectionRipDetectionContaminationDetectionLabel = uilabel(app.UIFigure);
            app.HoleDetectionRipDetectionContaminationDetectionLabel.Text = {'-Hole Detection'; '-Rip Detection'; '-Contamination Detection'};
            app.BurnHolesDetectionStainsDetectionMissingFingerDetectionLabel_3 = uilabel(app.UIFigure);
            app.BurnHolesDetectionStainsDetectionMissingFingerDetectionLabel_3.Text = {'-Breach of Glove Finger Detection'; '-Dirty Glove Detection'; '-Tear of Glove Cuff Detection'};
            app.CottonGloveButton = uibutton(app.UIFigure, 'push');
            app.CottonGloveButton.ButtonPushedFcn = createCallbackFcn(app, @CottonGloveButtonPushed, true);
            app.CottonGloveButton.Text = 'Cotton Glove';
            app.WelcometotheGloveDetectionWorldLabel = uilabel(app.UIFigure);
            app.WelcometotheGloveDetectionWorldLabel.Text = 'Welcome to the Glove Detection World !!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainpage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end